classdef handEyeCalibration < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        HandEyeCalibrationUIFigure      matlab.ui.Figure
        TabGroup                        matlab.ui.container.TabGroup
        DataTab                         matlab.ui.container.Tab
        ImagesPanel                     matlab.ui.container.Panel
        ImagesListBox                   matlab.ui.control.ListBox
        ImageView                       matlab.ui.control.UIAxes
        PosesPanel                      matlab.ui.container.Panel
        PosesTabGroup                   matlab.ui.container.TabGroup
        JointAnglesTab                  matlab.ui.container.Tab
        JointsUITable                   matlab.ui.control.Table
        CartesianCoordinatesTab         matlab.ui.container.Tab
        PosesUITable                    matlab.ui.control.Table
        ImagesButtonPanel               matlab.ui.container.Panel
        LoadImageButton                 matlab.ui.control.Button
        RemoveImage                     matlab.ui.control.Button
        RemovePoseCheckBox              matlab.ui.control.CheckBox
        ImagesLabel                     matlab.ui.control.Label
        RemoveAllImages                 matlab.ui.control.Button
        PosesButtonPanel                matlab.ui.container.Panel
        AddPoseButton                   matlab.ui.control.Button
        RemovePoseButton                matlab.ui.control.Button
        LoadPoseButton                  matlab.ui.control.Button
        PosesLabel                      matlab.ui.control.Label
        SelectedRowLabel                matlab.ui.control.Label
        RowLabel                        matlab.ui.control.Label
        RemoveAllPoses                  matlab.ui.control.Button
        StartButton                     matlab.ui.control.Button
        SourceDropDownLabel             matlab.ui.control.Label
        SourceDropDown                  matlab.ui.control.DropDown
        DataProgressLamp                matlab.ui.control.Lamp
        RealTimeExecutionLabel          matlab.ui.control.Label
        RealDataLabel                   matlab.ui.control.Label
        ProgressLabel                   matlab.ui.control.Label
        CalibrationTab                  matlab.ui.container.Tab
        CalibrationButtonPanel          matlab.ui.container.Panel
        CalibrateButton                 matlab.ui.control.Button
        SaveCalibButton                 matlab.ui.control.Button
        TileSizeEditFieldLabel          matlab.ui.control.Label
        TileSizeEditField               matlab.ui.control.NumericEditField
        CalibProgress                   matlab.ui.control.Lamp
        CalibrationLabel                matlab.ui.control.Label
        CalibProgressLabel              matlab.ui.control.Label
        UnitsLabel                      matlab.ui.control.Label
        ShowInteraractiveIntrinsicsCheckBox  matlab.ui.control.CheckBox
        ExtrinsicsPanel                 matlab.ui.container.Panel
        Extrinsics                      matlab.ui.control.UIAxes
        TabGroup2                       matlab.ui.container.TabGroup
        HandEyeCorrectedWTab            matlab.ui.container.Tab
        HEWCorCalibUITable              matlab.ui.control.Table
        HEWRCorPYUITable                matlab.ui.control.Table
        HandEyeWTab                     matlab.ui.container.Tab
        HEWCalibUITable                 matlab.ui.control.Table
        HEWRPYUITable                   matlab.ui.control.Table
        HandEyeCorrectedLTab            matlab.ui.container.Tab
        HELCorCalibUITable              matlab.ui.control.Table
        HELRCorPYUITable                matlab.ui.control.Table
        HandEyeLTab                     matlab.ui.container.Tab
        HELCalibUITable                 matlab.ui.control.Table
        HELRPYUITable                   matlab.ui.control.Table
        IntrinsicsPanel                 matlab.ui.container.Panel
        PixelError                      matlab.ui.control.UIAxes
        InstrinsicCalibrationpixelPanel  matlab.ui.container.Panel
        InstrinsiCalibUITable           matlab.ui.control.Table
        SettingsTab                     matlab.ui.container.Tab
        ImageCaptureServerPanel         matlab.ui.container.Panel
        CaptureServerEditFieldLabel     matlab.ui.control.Label
        CaptureServerEditField          matlab.ui.control.EditField
        ArgumentsEditFieldLabel         matlab.ui.control.Label
        ArgumentsEditField              matlab.ui.control.EditField
        BrowseCaptureServerButton       matlab.ui.control.Button
        CalibrationPanel                matlab.ui.container.Panel
        BrowseCalibImgButton            matlab.ui.control.Button
        CalibrationImagesEditFieldLabel  matlab.ui.control.Label
        CalibrationImagesEditField      matlab.ui.control.EditField
        VariablesButton                 matlab.ui.control.Button
        VariablesEditFieldLabel         matlab.ui.control.Label
        VariablesEditField              matlab.ui.control.EditField
        JointAnglesEditFieldLabel       matlab.ui.control.Label
        JointAnglesEditField            matlab.ui.control.EditField
        CartesianPosesEditFieldLabel    matlab.ui.control.Label
        CartesianPosesEditField         matlab.ui.control.EditField
        CameraParametersEditFieldLabel  matlab.ui.control.Label
        CameraParametersEditField       matlab.ui.control.EditField
        HandEyeCalibrationEditField_2Label_2  matlab.ui.control.Label
        HandEyeCalibrationNameEditField  matlab.ui.control.EditField
        UnitsDropDownLabel              matlab.ui.control.Label
        UnitsDropDown                   matlab.ui.control.DropDown
        LoadConfigButton                matlab.ui.control.Button
        SaveConfigButton                matlab.ui.control.Button
        ConfigurationPanel              matlab.ui.container.Panel
        BrowseConfig                    matlab.ui.control.Button
        ConfigurationEditFieldLabel     matlab.ui.control.Label
        ConfigurationEditField          matlab.ui.control.EditField
        HandEyeCalibrationSettingsPanel  matlab.ui.container.Panel
        BrowseHandEye                   matlab.ui.control.Button
        HandEyeCalibrationEditField_2Label  matlab.ui.control.Label
        HandEyeCalibrationEditField     matlab.ui.control.EditField
        VrepSimulationPanel             matlab.ui.container.Panel
        SimulationLabel                 matlab.ui.control.Label
        VrepEditField                   matlab.ui.control.EditField
        VrepBrowseButton                matlab.ui.control.Button
        SceneEditFieldLabel             matlab.ui.control.Label
        SceneEditField                  matlab.ui.control.EditField
        SceneBrowseButton               matlab.ui.control.Button
        ArgumentsEditField_2Label       matlab.ui.control.Label
        VrepArgsEditField               matlab.ui.control.EditField
        VrepAutoStartCheckBox           matlab.ui.control.CheckBox
        ReconnecttimesSpinnerLabel      matlab.ui.control.Label
        ReconTimesSpinner               matlab.ui.control.Spinner
        ReconnecttimesEditFieldLabel    matlab.ui.control.Label
        ReconTimeEditField              matlab.ui.control.NumericEditField
        ROSPanel                        matlab.ui.container.Panel
        NodeNameEditFieldLabel          matlab.ui.control.Label
        NodeNameEditField               matlab.ui.control.EditField
        MasterIPEditFieldLabel          matlab.ui.control.Label
        MasterIPEditField               matlab.ui.control.EditField
        LocalIPEditFieldLabel           matlab.ui.control.Label
        LocalIPEditField                matlab.ui.control.EditField
        RobotVelocityEditFieldLabel     matlab.ui.control.Label
        RobotVelocityEditField          matlab.ui.control.NumericEditField
        LogTab                          matlab.ui.container.Tab
        Log                             matlab.ui.control.TextArea
        HelpTab                         matlab.ui.container.Tab
        OpenHelpButton                  matlab.ui.control.Button
        OpenDocsButton                  matlab.ui.control.Button
        ScanObjectsTab                  matlab.ui.container.Tab
    end

    
    properties (Access = private)
        % Base home path of app
        homePath = fileparts(mfilename('fullpath'));
        
        % Name for simulation in app.SourceDropDown
        simName = 'Simulation';
        realRobotName = 'Real Robot';
        % Names for poses panel
        joinTabName = 'Joint Angles';
        cartTabName = 'Cartesian Poses';
        % File to store last used settings
        lastUsedName = 'lastUsed.ini';
        
        % Var to store the app.JointsAnglesTab
        jointTab = [];
        
        % Sections for *.ini files
        configSections = {'Calibration', 'Hand Eye Calibration', 'Simulation', 'Capture Server', 'ROS', 'Configuration'}; % Description
        lastUsedSections = {'Image Paths', 'Poses', 'Configuration'};
        
        % Initialize values for calibration
        handEyeCorrectedWengert = [];
        handEyeWengert = [];
        handEyeCorrectedLazax = [];
        handEyeLazax = [];
        
        % Names for positions
        posesColumnNameBase = {'X', 'Y', 'Z'}
        
        % Current selected row
        selectedPosesRow = 0;
        
        % State of app
        runsCalibration = 0;
        runsDataAcquisition = 0;
        
        % Structs to store connection variables
        rosCon = [];
        simCon = [];
        
        % Icon path for info msgbox
        infoIcon = [];
        
        % Waitbar handle
        waitBar = 0;
        
        %Figure components to show extrinsics
        extrinsicsFig = [];
        extrinsicsAx = [];
        
        % Splash path
        splashScreenDir = ''
     
        % Flag to categorize loaded cartesian poses
        simData = 0; 
    end
    
    methods (Access = private)
        
        % Logger to print on log
        % msg: Message to print
        function logger(app, msg)
            val = app.Log.Value;
            % Log is static textfield, has to be refield
            tmp = [val; '[' datestr(datetime('now')) ']: ' msg];
            app.Log.Value = transpose(tmp);
        end
        
        % Check to override poses
        function res = checkOverridePoses(app)
            res = questdlg('Do you want to override data in the current tab?', 'Override data');
        end
        
        % Check if position data is valid: abs(X+Y+Z) > 0
        function results = checkCalibData(app)
            results = 1;
            len = size(app.PosesUITable.Data, 1);
            
            for i=1:len
                if sum(abs(app.PosesUITable.Data(i, 1:3))) == 0
                    logger(app, 'Warning: At least one position is at (0, 0, 0). Is this correct?');
                    results = 0;
                    break;
                end
            end
        end
        
        % Check valid number of data: Images == Poses
        function res = checkCalibDataNum(app)
            res = 1;
            imgNum = size(app.ImagesListBox.Items, 2);
            cartPosNum = size(app.PosesUITable.Data, 1);
            
            if imgNum ~= cartPosNum
                errorMsg(app, 'Wrong Amount Of Calibration Data', 'Number of calibration images and calibration poses are different! Please remove or add images/poses to an equal number!');
                
                res = 0;
            elseif imgNum == 0 || cartPosNum == 0
                errorMsg(app, 'Calibration Data Missing', 'Number of calibration images and/or number calibration poses is zero! Please add images and/or poses!');
                res = 0;
            end
        end
        
        % Change columns to meter or mm for all colums containing X, Y, Z
        % depends on app.UnitsDropDown
        function changeCartPoseColumnNames(app)
            for i = 1:3
                name = [app.posesColumnNameBase{i} ' (' app.UnitsLabel.Text ')'];
                app.PosesUITable.ColumnName{i} = name;
                app.HEWRPYUITable.ColumnName{i} = name;
                app.HEWRCorPYUITable.ColumnName{i} = name;
                app.HELRPYUITable.ColumnName{i} = name;
                app.HELRCorPYUITable.ColumnName{i} = name;
            end
        end
        
        % Delete poses from joint angles and poses tab
        % index: Selected index to delete
        function deletePosesRow(app, index)
            len = size(app.PosesUITable.Data, 1);
            if index <= len
                %                             app.PosesUITable.RowName(index) = [];
                app.PosesUITable.Data(index, :) = [];
            else
                logger(app, ['Warning: Not enough cartesian poses available to delete index: ' num2str(index)]);
            end
            
            len = size(app.JointsUITable.Data, 1);
            if index <= len
                %                             app.JointsUITable.RowName(index) = [];
                app.JointsUITable.Data(index, :) = [];
            else
                logger(app, ['Warning: Not enough joint angles available to delete index: ' num2str(index)]);
            end
        end
        
        % Toggle enable state of a button
        % button: Button handle
        function newState = changeBtnState(app, button)
            state = button.Enable;
            switch state
                case 'on'
                    button.Enable = 'off';
                case 'off'
                    button.Enable = 'on';
            end
            newState = button.Enable;
        end
        
        % Enable a button
        % button: Button handle
        function enableButton(app, button)
            button.Enable = 'on';
        end
        
        % Disable a button
        % button: BUtton handle
        function disableButton(app, button)
            button.Enable = 'off';
        end
        
        % Start simualation executable
        function res = startSimExecutable(app)
            % Use " to work with spaces in paths (Windows)
            systemCall = ['"' app.VrepEditField.Value '" ' app.VrepArgsEditField.Value ' ' app.SceneEditField.Value ' &'];
            res = system(systemCall);
            
            if ~res
                logger(app, ['VREP started with args ' app.VrepArgsEditField.Value]);
            else
                warningMsg(app, 'VREP Start Failure', ['Could not start VREP with ' systemCall]);
            end
            res = ~res;
            
        end
        
        % Error dialog box
        % title: Dialog title
        % string: Dialog message
        function errorMsg(app, title, string)
            if ~strcmp(app.HandEyeCalibrationUIFigure.Visible,'off')
                uiwait(errordlg(string, title, 'modal'));
            end
        end
        
        % Warning dialog box
        % title: Dialog title
        % string: Dialog message
        function warningMsg(app, title, string)
            if ~strcmp(app.HandEyeCalibrationUIFigure.Visible,'off')
                uiwait(warndlg(string, title, 'modal'));
            end
        end
        
        % Load configuration and set components
        % configDir: Path + filename to configuration file
        function loadConfig(app, configDir)
            % Definied sections
            %             {'Calibration', 'Hand Eye Calibration', 'Simulation', 'Capture Server', 'ROS', 'Configuration'};
            
            sections = getAllSections(configDir);
            
            for i = 1:length(sections)
                % Read one section
                section = readConfig(configDir, sections(i));
                % Change values depending on section, see config file
                switch sections{i}
                    case app.configSections(1)
                        app.VariablesEditField.Value = section.varDir;
                        app.CalibrationImagesEditField.Value = section.imgDir;
                        app.JointAnglesEditField.Value = section.jointAngles;
                        app.CartesianPosesEditField.Value = section.cartPoses;
                        app.UnitsDropDown.Value = section.units;
                        app.UnitsLabel.Text = section.units;
                        app.TileSizeEditField.Value = section.tileSize;
                    case app.configSections(4)
                        app.CaptureServerEditField.Value = section.dir;
                        app.ArgumentsEditField.Value = section.args;
                    case app.configSections(5)
                        app.MasterIPEditField.Value = section.masterIP;
                        app.LocalIPEditField.Value = section.localIP;
                        app.NodeNameEditField.Value = section.nodeName;
                        app.RobotVelocityEditField.Value = section.robotVelocity;
                    case app.configSections(6)
                        app.ConfigurationEditField.Value = section.dir;
                    case app.configSections(3)
                        app.VrepEditField.Value = section.dir;
                        app.SceneEditField.Value = section.scene;
                        app.VrepArgsEditField.Value = section.args;
                        app.VrepAutoStartCheckBox.Value = section.autoStart;
                        app.ReconTimesSpinner.Value = section.reconTimes;
                        app.ReconTimeEditField.Value = section.reconTime;
                    case app.configSections(2)
                        app.HandEyeCalibrationEditField.Value = section.dir;
                end
            end
        end
        
        % Execute function to get image data from source
        % source: Data source, currently Simulation and Real Robot
        function res = executeSource(app, source)
            poseData = [];
            dataLen = 0;
            
            imgPath = '';
            imgName = '';
            
            res = 1;
            
            override = '';
            
            % Check if override current image data
            if ~isempty(app.ImagesListBox.Items)
                override = checkOverrideImages(app);
            end
            
            if ~strcmp(override, 'Cancel')
                
                % Clear data if override
                if strcmp(override, 'Yes')
                    app.ImagesListBox.Items = {};
                    app.ImagesListBox.ItemsData = [];
                end
                
                switch source
                    % Source = Simulation
                    case app.simName
                        % Get cartesian poses
                        poseData = app.PosesUITable.Data;
                        dataLen = size(app.PosesUITable.Data, 1);
                        
                        % Check poses
                        if size(poseData, 2) ~= 6
                            str = ['Supplied wrong format for cartesian poses: ' size(poseData, 2) '! Six entries needed (X, Y, Z, RY, RY, RZ)!'];
                            logger(app, str);
                            errorMsg(app, 'Wrong Cartesian Poses Format', ['Supplied wrong format for cartesian poses: ' size(poseData, 2) '! Six etnries needed (X, Y, Z, RY, RY, RZ)!']);
                        end
                    case app.realRobotName
                        % Source = Real Robot
                        poseData = app.JointsUITable.Data;
                        dataLen = size(app.JointsUITable.Data, 1);
                        
                        % Check poses
                        if size(poseData, 2) ~= 7
                            str = ['Supplied wrong number of joint angles: ' size(poseData, 2) '! Seven joint angles needed!'];
                            logger(app, str);
                            errorMsg(app, 'Wrong Joint Angles Number', ['Supplied wrong number of joint angles: ' size(poseData, 2) '! Seven joint angles needed!']);
                        end             
                end
                
                % Start progress bar
                app.waitBar = waitbar(0, ['Simulating pose: 1 of ' num2str(dataLen)],...
                    'Name', 'Simulating data acquisition');
                for i = 1:dataLen
                    % Update progress bar
                    waitbar(i/dataLen, app.waitBar, ['Simulating pose: ' num2str(i) ' of ' num2str(dataLen)]);
                    
                    switch source
                        % Source = Simulation
                        case app.simName
                            % Set a pose
                            res = setTcpPose(app.simCon, poseData(i, :));
                            if ~res
                                errorMsg(app, 'Simulated Cartesian Robot Movement Error', 'Commands to move TCP failed! Please check log and implementation!');
                                str = ['Moving to pose ' i ' at ' poseData(i, :) ' failed! TCP available in simulation?'];
                                logger(app, str);
                                break;
                            else
                                % Take picture, exceptions are not handle
                                [imgRes, imgPath, imgName] = takePictureSimulation(app.simCon, i, app.CalibrationImagesEditField.Value);
                                
                                if ~imgRes
                                    errorMsg(app, 'Simulated Camera Error', 'Commands to take a picture failed! Please check log and implementation!');
                                    str = ['Take picture at pose ' i ' with ' num2str(poseData(i, :)) ' failed! Camera available in simulation?'];
                                    logger(app, str);
                                    break;
                                end
                            end
                            % Source = Real Robot
                        case app.realRobotName
                            % Move robot via joints angles
                            res = moveRobotToJointAngles(app.rosCon, poseData(i, :), app.RobotVelocityEditField.Value);
                            
                            if ~res
                                errorMsg(app, 'Joint Space Robot Movement Error', 'Commands to move robot failed! Please check log and implementation!');
                                str = ['Moving to joint angles ' i ' at ' num2str(poseData(i, :)) ' failed! Joint angles reachable?'];
                                logger(app, str);
                                break;
                            else
                                % Take picture, exceptiosn not handled
                                [imgPath, imgName] = takePictureRobot(500.0, 10, app.CalibrationImagesEditField.Value);
                                
                                % Get cartesian pose
                                robotPose = getRobotPose(app.rosCon);
                                app.PosesUITable.Data = [app.PosesUITable.Data; robotPose.Cart];
                            end
                    end
                    % Update images
                    insertImages(app, imgName, imgPath);
                    
                    enableButton(app, app.RemoveImage);
                end
                delete(app.waitBar);
            end
        end
        
        % Start a server to take images with real robot
        function res = startCaptureServer(app)
            % Use " to handle spaces in path(Windows)
            systemCall = ['"' app.CaptureServerEditField.Value '" ' app.ArgumentsEditField.Value ' &'];
            res = system(systemCall);
            
            if ~res
                logger(app, ['Capture server started with args ' app.ArgumentsEditField.Value]);
                
            else
                warningMsg(app, 'Capture Server Start Failure', ['Could not start capture server with ' systemCall]);
            end
            res = ~res;
        end
        
        % Connect to ros
        function res = startROS(app)
            res = 1;
            
            % Store data in connection struct
            app.rosCon = rosConnection(app.MasterIPEditField.Value, app.LocalIPEditField.Value, app.NodeNameEditField.Value);
            % Get current real robot pose for checking
            robotPose = getRobotPose(app.rosCon);
            
            if isempty(robotPose.cartLWR1) || isempty(robotPose.jointsLWR1)
                errorMsg(app, 'Robot Pose Error', 'Could not retrieve robot pose! Please check connection to robot!');
                res = 0;
            end
        end
        
        % Connect to simulation
        function res = simConnection(app, autoSimStart)
            res = 1;
            count = 1;
            
            % Try to connect, maybe sim is running
            [app.simCon] = connectToSimulation();
            
            % If sim not running
            if app.simCon.clientID == -1
                % Try for n times specified in app.ReconTimeSpinner
                while count <= app.ReconTimesSpinner.Value && autoSimStart
                    [app.simCon] = connectToSimulation();
                    
                    if app.simCon.clientID == -1
                        res = 0;
                        logger(app, ['Error: VREP id is ' num2str(app.simCon.clientID) '! Reconnecting: ' count]);
                    else
                        res = 1;
                        break;
                    end
                    % Wait till retrying
                    pause(app.ReconTimeEditField.Value);
                    count = count + 1;
                end
            end
        end
        
        % Error dialog box
        % title: Dialog title
        % string: Dialog message
        function infoMsg(app, title, string)
            if ~strcmp(app.HandEyeCalibrationUIFigure.Visible, 'off')
                uiwait(msgbox(string, title,'custom', app.infoIcon.cdata, app.infoIcon.map, 'modal'));
            end
            
        end
        
        % Create a splash screen with bar
        function s = createSplash(app)
            app.HandEyeCalibrationUIFigure.Visible = 'off';
            s = SplashScreen( 'Splashscreen', app.splashScreenDir, ...
                'ProgressBar', 'on', ...
                'ProgressPosition', 10, ...
                'ProgressRatio', 0.0 );
            s.addText( 30, 50, 'Hand Eye Calibration', 'FontSize', 30, 'Color', [0 0 0.6] );
            s.addText( 30, 80, 'v1.0', 'FontSize', 20, 'Color', [0.2 0.2 0.5] );
            %             s.addText( 300, 270, 'Loading...', 'FontSize', 20, 'Color', 'white' )
        end
        
        % Add image to current images
        % filenames: names displaye in listbox
        % dirs: Paths + filenames for images
        function insertImages(app, filenames, dirs)
            oldItems = app.ImagesListBox.Items;
            oldData = app.ImagesListBox.ItemsData;
            newItems = cell(1, length(oldItems) + length(filenames));
            newData = cell(1, length(oldItems) + length(dirs));
            
            for i = 1:length(oldItems)
                newItems(1, i) = oldItems(1, i);
                newData(1, i) = oldData(1, i);
            end
            
            for i = length(oldItems) + 1:length(oldItems) + length(filenames)
                newItems(1, i) = filenames(1, i - length(oldItems));
                newData(1, i) = dirs(1, i - length(oldItems));
            end
            
            app.ImagesListBox.Items = newItems;
            app.ImagesListBox.ItemsData = newData;
        end
        
        % Check if override images
        function res = checkOverrideImages(app)
            res = questdlg('Do you want to override current images?', 'Override images');
        end
        
        % Create suitable representation for camera poses
        function poses = createPosesFromCameraParams(app, cameraParams)
            poses.correctedOrientationMatrix = cameraParams.RotationMatrices;
            
            for i = 1:size(cameraParams.TranslationVectors, 1)
                poses.correctedTranslationVector(1, :, i) = cameraParams.TranslationVectors(i, :);
            end
            
        end
        
        % Load last used data
        function [imgRes, posesRes] = restoreLastUsed(app)
            sections = getAllSections(app.lastUsedName);
            for i = 1:length(sections)
                % Read one section
                section = readConfig(app.lastUsedName, sections(i));
                
                switch sections{i}
                    case app.lastUsedSections(1)
                        app.ImagesListBox.Items = section.Names;
                        app.ImagesListBox.ItemsData = section.Paths;
                    case app.lastUsedSections(2)
                        app.PosesUITable.Data = section.Cart;
                        app.JointsUITable.Data = section.Joints;
                        app.simData = section.simData;
                    case app.lastUsedSections(3)
                        app.ConfigurationEditField.Value = section.dir;
                end
            end
            imgRes = ~isempty(app.ImagesListBox.ItemsData);
            posesRes = ~(isempty(app.PosesUITable.Data) && isempty(app.JointsUITable.Data));
            str = ['Loaded last used data from ' app.lastUsedName];
            logger(app, str);
        end
        
        % Convert hand eye calibration to RPY
        function setReadableHE(app)
            if ~isempty (app.HEWCalibUITable.Data)
                [roll, pitch, yaw] = rotationMatrixToRPY(app.HEWCalibUITable.Data);
                app.HEWRPYUITable.Data = [app.handEyeWengert(1, 4), app.handEyeWengert(2, 4), app.handEyeWengert(3, 4), roll, pitch, yaw];
            end
            
            if ~isempty (app.HEWCorCalibUITable.Data)
                [roll, pitch, yaw] = rotationMatrixToRPY(app.HEWCorCalibUITable.Data);
                app.HEWRCorPYUITable.Data = [app.handEyeCorrectedWengert(1, 4), app.handEyeCorrectedWengert(2, 4), app.handEyeCorrectedWengert(3, 4), roll, pitch, yaw];
            end
            
            if ~isempty (app.HELCalibUITable.Data)
                [roll, pitch, yaw] = rotationMatrixToRPY(app.HELCalibUITable.Data);
                app.HELRPYUITable.Data = [app.handEyeLazax(1, 4), app.handEyeLazax(2, 4), app.handEyeLazax(3, 4), roll, pitch, yaw];
            end
            
            if ~isempty (app.HELCorCalibUITable.Data)
                [roll, pitch, yaw] = rotationMatrixToRPY(app.HELCorCalibUITable.Data);
                app.HELRCorPYUITable.Data = [app.handEyeCorrectedLazax(1, 4), app.handEyeCorrectedLazax(2, 4), app.handEyeCorrectedLazax(3, 4), roll, pitch, yaw];
            end
        end
        
        % Check if all images in listbox exist
        function res = checkImageExist(app)
            res = 1;
            for i = 1:size(app.ImagesListBox.ItemsData, 2)
                if exist(app.ImagesListBox.ItemsData{i}, 'file') ~= 2
                    logger(app, ['Image at ' app.ImagesListBox.ItemsData{i} ' does not exist!']);
                    res = 0;
                    break;
                end
            end
            
        end
        
        % Create special figure for calibration extrinsics
        function fig = createExtrinsicsFigure(app)
            fig = figure('Visible', 'off', 'MenuBar', 'none', 'Name', 'Extrinsics', 'NumberTitle', 'off', 'CloseRequestFcn', '');
            app.extrinsicsAx = axes('Parent', fig);
            app.extrinsicsAx.YAxis.Visible = 'Off';
            app.extrinsicsAx.XAxis.Visible = 'Off';
            app.extrinsicsAx.Title.String = '';
            
        end
        
        % Take a picture from extrinsics figure
        function img = getExtrinsicsImage(app)
            app.extrinsicsAx.Title.String = '';
            frame = getframe(app.extrinsicsFig);
            img = frame.cdata;
        end
        
        % Check if poses tabs are empty
        function checkPosesTablesEmpty(app)
            if isempty(app.PosesUITable.Data) && isempty(app.JointsUITable.Data)
                app.selectedPosesRow = 0;
                app.RowLabel.Text = num2str(0);
                disableButton(app, app.RemovePoseButton);
            end
        end
        
        % Convert data orietation to matlab 'ZYX' convention
        function data = convertDataFromSim(app)
            for i = 1:size(app.PosesUITable.Data, 1)
                tcpPose = convertSimData(app.PosesUITable.Data(i, 1:3), deg2rad(app.PosesUITable.Data(i, 4:6)));
                [r, p, y] = rotationMatrixToRPY(tcpPose(1:3, 1:3));
                vec = [tcpPose(1, 4), tcpPose(2, 4), tcpPose(3, 4), r, p, y];
                data(i,:) = vec;
            end
        end
        
        function res = checkOverrideCalib(app)
            res = questdlg('Do you want to override existing calibration?', 'Override images', 'Yes', 'No', 'No');
        end
    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            lastUsed = 0;
            
            logger(app, 'Initializing...');
            
            % Load main app folder
            appPath = [app.homePath '\app'];
            
            if ~exist(appPath, 'dir')
                uialert(app.HandEyeCalibrationUIFigure, ['Important folder ' appPath 'not found! This app is not functioning without this folder.Please make sure it is included to the app root directory!'],...
                    'Critical Error');
                exit(-1);
            else
                addpath(genpath(appPath));
            end
            
            % Path to splashscreen
            app.splashScreenDir = [app.homePath '\app\icons\splash2.png'];
            s = createSplash(app);
            
            % Load last used configuration if available
            if exist(app.lastUsedName, 'file')
                [imgRes, posesRes] = restoreLastUsed(app);
                lastUsed = 1;
                if ~imgRes
                    disableButton(app, app.RemoveImage);
                end
                
                if ~posesRes
                    disableButton(app, app.RemovePoseButton);
                end
            else
                disableButton(app, app.RemoveImage);
                disableButton(app, app.RemovePoseButton);
            end
            
            % Load own info icon
            [app.infoIcon.cdata, app.infoIcon.map] = imread('info.png');
            
            s.ProgressRatio = 0.25;
            pause(0.5);
            
            % Settings for poses tables
            app.PosesUITable.ColumnFormat = {'numeric','numeric','numeric','numeric','numeric','numeric'};
            %                          app.PosesUITable.ColumnWidth = {80, 80, 80, 80, 80, 80};
            app.PosesUITable.ColumnEditable = true;
            %             app.PosesUITable.Data(1,:) = [100.123, 100, 100, 10, 15, 20];
            %             app.PosesUITable.RowName(1) = {1};
            
            app.JointsUITable.ColumnFormat = {'numeric','numeric','numeric','numeric','numeric','numeric','numeric'};
            app.JointsUITable.ColumnWidth = {69, 69, 69, 69, 69, 69, 69};
            app.JointsUITable.ColumnEditable = true;
            %             app.JointsUITable.Data(1,:) = [10.123, 10, 10, 1, 15, 20, 78];
            %             app.JointsUITable.RowName(1) = {1};
            
            s.ProgressRatio = 0.5;
            
            % Settings for hand eye calibration tables
            app.HEWCorCalibUITable.RowName(1) = {1};
            app.HEWCorCalibUITable.RowName(2) = {2};
            app.HEWCorCalibUITable.RowName(3) = {3};
            app.HEWCorCalibUITable.RowName(4) = {4};
            app.HEWCorCalibUITable.Data = app.handEyeCorrectedWengert;
            
            app.HEWCalibUITable.RowName(1) = {1};
            app.HEWCalibUITable.RowName(2) = {2};
            app.HEWCalibUITable.RowName(3) = {3};
            app.HEWCalibUITable.RowName(4) = {4};
            app.HEWCalibUITable.Data = app.handEyeWengert;
            
            app.HELCorCalibUITable.RowName(1) = {1};
            app.HELCorCalibUITable.RowName(2) = {2};
            app.HELCorCalibUITable.RowName(3) = {3};
            app.HELCorCalibUITable.RowName(4) = {4};
            app.HELCorCalibUITable.Data = app.handEyeCorrectedLazax;
            
            app.HELCalibUITable.RowName(1) = {1};
            app.HELCalibUITable.RowName(2) = {2};
            app.HELCalibUITable.RowName(3) = {3};
            app.HELCalibUITable.RowName(4) = {4};
            app.HELCalibUITable.Data = app.handEyeLazax;
            
            app.InstrinsiCalibUITable.RowName(1) = {1};
            app.InstrinsiCalibUITable.RowName(2) = {2};
            app.InstrinsiCalibUITable.RowName(3) = {3};
            app.InstrinsiCalibUITable.Data = eye(3);
            
            % Change table names
            setReadableHE(app);
            
            % Settings for axes/figures
            app.ImageView.YAxis.Visible = 'Off';
            app.ImageView.XAxis.Visible = 'Off';
            app.ImageView.Title.Interpreter = 'none';
            
            app.Extrinsics.YAxis.Visible = 'Off';
            app.Extrinsics.XAxis.Visible = 'Off';
            app.Extrinsics.Title.Interpreter = 'none';
            
            % Rearange tabs
            app.PosesTabGroup.Children(1).Title = app.joinTabName;
            app.PosesTabGroup.Children(2).Title = app.cartTabName;
            app.PosesTabGroup.Children = [app.PosesTabGroup.Children(2); app.PosesTabGroup.Children(1)];
            app.PosesTabGroup.SelectedTab = app.PosesTabGroup.Children(1);
            
            s.ProgressRatio = 0.75;
            
            % Set Settings fields
            app.CalibrationImagesEditField.Value = [app.homePath app.CalibrationImagesEditField.Value];
            app.VariablesEditField.Value = [app.homePath app.VariablesEditField.Value];
            app.CaptureServerEditField.Value = [app.homePath app.CaptureServerEditField.Value];
            app.HandEyeCalibrationEditField.Value = [app.VariablesEditField.Value app.HandEyeCalibrationEditField.Value];
            app.SceneEditField.Value = [app.homePath app.SceneEditField.Value];
            
            if ~lastUsed
                app.ConfigurationEditField.Value = [app.homePath app.ConfigurationEditField.Value];
            end
            
            configDir = app.ConfigurationEditField.Value;
            
            % Load configuration/settings
            if exist(configDir, 'file') == 0
                logger(app, ['Configuration missing, creating new one at ' configDir]);
                SaveConfigButtonPushed(app);
            else
                LoadConfigButtonPushed(app)
                changeCartPoseColumnNames(app);
            end
            
            % Create empty extrinsics figure
            app.extrinsicsFig = createExtrinsicsFigure(app);
            
            logger(app, 'Initialization complete!');
            
            app.HandEyeCalibrationUIFigure.Visible = 'on';
            s.ProgressRatio = 1;
            pause(0.5);
            
            delete(s);
        end

        % Button pushed function: AddPoseButton
        function AddPoseButtonPushed(app, event)
            curTab = app.PosesTabGroup.SelectedTab.Title;
            
            % Add empty pose depending on selected tab
            switch(curTab)
                case app.cartTabName
                    len = size(app.PosesUITable.Data, 1);
                    app.PosesUITable.Data(len + 1, :) = [0, 0, 0, 0, 0, 0];
                case app.joinTabName
                    len = size(app.JointsUITable.Data, 1);
                    app.JointsUITable.Data(len + 1, :) = [0, 0, 0, 0, 0, 0, 0];
            end
            
            enableButton(app, app.RemovePoseButton);
        end

        % Button pushed function: BrowseCalibImgButton
        function BrowseCalibImgButtonPushed(app, event)
            path = uigetdir(app.homePath,'Select Images Directory');
            
            if length(path) > 1
                app.CalibrationImagesEditField.Value = path;
            end
        end

        % Button pushed function: BrowseCaptureServerButton
        function BrowseCaptureServerButtonPushed(app, event)
            [path, ~, ~] = fileparts(app.CaptureServerEditField.Value);
            [filename, path] = uigetfile({'*.exe'},'Capture Server Executable');
            
            if length(filename) > 1
                path = [path filename];
                app.CaptureServerEditField.Value = path;
            end
        end

        % Button pushed function: BrowseConfig
        function BrowseConfigButtonPushed(app, event)
            [path, ~, ~] = fileparts(app.ConfigurationEditField.Value);
            [filename, path] = uigetfile({'*.ini'},'Configuration File');
            
            if length(filename) > 1
                configDir = [path filename];
                app.ConfigurationEditField.Value = configDir;
            end
        end

        % Button pushed function: BrowseHandEye
        function BrowseHandEyeButtonPushed(app, event)
            [filename, path] = uigetfile({'*.mat'},'Hand Eye Matrix File', app.VariablesEditField.Value);
            
            if length(filename) > 1
                path = [path filename];
                app.HandEyeCalibrationEditField.Value = path;
            end
        end

        % Button pushed function: CalibrateButton
        function CalibrateButtonPushed(app, event)
            res = 'Yes';
            data = [];
            % Check data
            checkDataNum = checkCalibDataNum(app);
            if ~checkCalibData(app) && checkDataNum
                res = questdlg('Calibration data is odd, please check the log! Do you want to proceed?', 'Odd calibration data', 'Yes', 'No', 'No');
            end
            
            % Check existence
            if checkImageExist(app)
                if checkDataNum
                    if strcmp(res, 'Yes')
                        res = 'Yes';
                        if ~isempty(app.HEWCalibUITable.Data)
                            res = checkOverrideCalib(app);
                        end
                                                
                        if strcmp(res, 'Yes')
                            logger(app, 'Starting instrinsic calibration...');
                            
                            % Change progress labels and lamp
                            app.CalibProgress.Color = 'yellow';
                            app.CalibProgressLabel.Text = 'Running';
                            app.runsCalibration = 1;
                            
                            disableButton(app, app.SaveCalibButton);
                            disableButton(app, app.CalibrateButton);
                            
                            % Intrinsics
                            [cameraParams, imagesUsed, correctedPoses, ~] = intrinsicCalib(app.ImagesListBox.ItemsData,...
                                app.TileSizeEditField.Value, app.UnitsDropDown.Value);
                            
                            app.waitBar = waitbar(0, 'Computing reprojections errors',...
                                'Name', 'Hand Eye Calibration', 'WindowStyle', 'modal');
                            showReprojectionErrors(cameraParams,'view', 'barGraph', 'Parent', app.PixelError);
                            
                            logger(app, ['Unused image mask: ' num2str(transpose(imagesUsed))]);
                            
                            % Extrinsics
                            waitbar(0.25, app.waitBar, 'Computing extrinsics');
                            showExtrinsics(cameraParams, 'View', 'PatternCentric', 'Parent', app.extrinsicsAx);
                            im = getExtrinsicsImage(app);
                            image(app.Extrinsics, im);
                            
                            logger(app, 'Instrinsic calibration complete!');
                            
                            logger(app, 'Starting hand eye calibration...');
                            
                            % Hand Eye
                            waitbar(0.50, app.waitBar, 'Computing hand eye calibration');
                            orgPoses = createPosesFromCameraParams(app, cameraParams);
                            
                            % Convert data to 'ZYX' for matlab from simulation
                            if app.simData
                                data = convertDataFromSim(app);
                            else
                                data = app.PosesUITable.Data;
                            end
                            
                            [app.handEyeCorrectedWengert, app.handEyeCorrectedLazax] = handEyeCalibrationTSAI(data, correctedPoses, imagesUsed);
                            [app.handEyeWengert, app.handEyeLazax] = handEyeCalibrationTSAI(data, orgPoses, imagesUsed);
                            
                            waitbar(0.75, app.waitBar, 'Populating hand eye calibration');
                            app.InstrinsiCalibUITable.Data = cameraParams.IntrinsicMatrix;
                            app.HEWCorCalibUITable.Data = app.handEyeCorrectedWengert;
                            app.HEWCalibUITable.Data = app.handEyeWengert;
                            app.HELCorCalibUITable.Data = app.handEyeCorrectedLazax;
                            app.HELCalibUITable.Data = app.handEyeLazax;
                            waitbar(1, app.waitBar, 'Populating hand eye calibration');
                            
                            app.CalibProgress.Color = 'green';
                            app.CalibProgressLabel.Text = 'Ready';
                            app.runsCalibration = 0;
                            setReadableHE(app);
                            
                            delete(app.waitBar);
                            
                            logger(app, 'Hand eye calibration complete!');
                            
                            enableButton(app, app.SaveCalibButton);
                            enableButton(app, app.CalibrateButton);
                        end
                    end
                end
            else
                errorMsg(app, 'Image(s) not exist', 'At least on image file does not exist! Please check log and image data!');
            end
        end

        % Close request function: HandEyeCalibrationUIFigure
        function HandEyeCalibrationUIFigureCloseRequest(app, event)
            % Save last used data
            imgPaths.Paths = app.ImagesListBox.ItemsData;
            imgPaths.Names = app.ImagesListBox.Items;
            
            poses.Cart = app.PosesUITable.Data;
            poses.Joints = app.JointsUITable.Data;
            poses.simData = app.simData;
            config.dir = app.ConfigurationEditField.Value;
            
            data = {imgPaths; poses; config};
            
            writeConfig('lastUsed.ini', app.lastUsedSections, data);
            
            if ishandle(app.extrinsicsFig)
                delete(app.extrinsicsFig);
            end
            
            delete(app);
        end

        % Value changed function: ImagesListBox
        function ImagesListBoxValueChanged(app, event)
            %Change selected table index
            % Load new image
            value = event.Value;
            path = char(value);
            
            app.RemoveImage.Enable = 'on';
            
            if ~isempty(app.ImagesListBox.ItemsData)
                
                if exist(path, 'file')
                    % Update title immediatelly
                    app.ImageView.Title.String = 'Loading...';
                    drawnow update;
                    img = imread(path);
                    image(app.ImageView, img);
                    
                    [~, name, ~] = fileparts(path);
                    app.ImageView.Title.String = name;
                    [~, index] = ismember(cellstr(value), cellstr(app.ImagesListBox.ItemsData));
                    
                    app.selectedPosesRow = index;
                    app.RowLabel.Text = num2str(index);
                else
                    errorMsg(app, 'Failed to load Image', ['Could not load image from ' path '! Does image exist?']);
                end
            else
                logger(app, 'Warning: No images available!');
            end
        end

        % Cell selection callback: JointsUITable
        function JointsUITableCellSelection(app, event)
            index = event.Indices(1);
            app.selectedPosesRow = index;
            app.RowLabel.Text = num2str(index);
        end

        % Button pushed function: LoadConfigButton
        function LoadConfigButtonPushed(app, event)
            configDir = app.ConfigurationEditField.Value;
            
            if exist(configDir, 'file') == 2
                loadConfig(app, configDir);
                str = ['Loaded configuration from ' app.ConfigurationEditField.Value];
                logger(app, str);
                infoMsg(app, 'Configuration Loaded', str);
            else
                str = ['Error: Could not load configuration at path: "' configDir '! Using default values.'];
                logger(app, str);
                errorMsg(app, 'Configuration Error', str);
            end
        end

        % Button pushed function: LoadImageButton
        function LoadImageButtonPushed(app, event)
            [filename, path] = uigetfile({'*.jpg; *.png; *.jpeg; *.bmp'},'Load Image(s)', app.CalibrationImagesEditField.Value, 'MultiSelect', 'on');
            
            if length(filename) > 1
                filename = cellstr(filename);
                path = cellstr(path);
                dirs = strcat(path, filename);
                override = '';
                
                % Check if override existing data
                if ~isempty(app.ImagesListBox.Items)
                    override = checkOverrideImages(app);
                end
                
                if ~strcmp(override, 'Cancel')
                    
                    if strcmp(override, 'Yes')
                        app.ImagesListBox.Items = {};
                        app.ImagesListBox.ItemsData = [];
                    end
                    
                    insertImages(app, filename, dirs);
                    
                    enableButton(app, app.RemoveImage);
                end
            end
        end

        % Button pushed function: LoadPoseButton
        function LoadPoseButtonPushed(app, event)
            [filename, path] = uigetfile({'*.mat'},'File Sector', app.VariablesEditField.Value);
            
            if filename ~= 0
                filename = cellstr(filename);
                path = cellstr(path);
                dir = strcat(path, filename);
                override = '';
                
                % Check override depending on current tab
                switch app.PosesTabGroup.SelectedTab.Title
                    case app.cartTabName
                        if ~isempty(app.PosesUITable.Data)
                            override = checkOverridePoses(app);
                        end
                    case app.joinTabName
                        if ~isempty(app.JointsUITable.Data)
                            override = checkOverridePoses(app);
                        end
                end
                
                if ~strcmp(override,'Cancel')
                    
                    % Add data depending on current tab
                    switch app.PosesTabGroup.SelectedTab.Title
                        case app.cartTabName
                            
                            if strcmp(override,'Yes')
                                app.PosesUITable.Data = [];
                            end
                            
                            res = questdlg('Is this simulation data?', 'Simulation data', 'Yes', 'No', 'No');
                            
                            if strcmp(res, 'Yes')
                                app.simData = 1;
                            else
                                app.simData = 0;
                            end
                            
                            data = load(char(dir), app.CartesianPosesEditField.Value);
                            
                            if isfield(data, app.CartesianPosesEditField.Value)
                                eval(['data = data.' app.CartesianPosesEditField.Value]);
                                len = size(app.PosesUITable.Data, 1);
                                
                                for i = 1:size(data, 1)
                                    app.PosesUITable.Data(len + i, :) = data(i,:);
                                end
                            else
                                str = ['Specified name ' app.CartesianPosesEditField.Value ' was not found in ' dir '!'];
                                uialert(app.HandEyeCalibrationUIFigure, str, 'Loading cartesian poses failed');
                            end
                            
                        case app.joinTabName
                            
                            if strcmp(override,'Yes')
                                app.JointsUITable.Data = [];
                            end
                            
                            data = load(char(dir), app.JointAnglesEditField.Value);
                            
                            if isfield(data, app.JointAnglesEditField.Value)
                                eval(['data = data.' app.JointAnglesEditField.Value]);
                                len = size(app.JointsUITable.Data, 1);
                                
                                for i = 1:size(data, 1)
                                    app.JointsUITable.Data(len + i, :) = data (i, :);
                                end
                            else
                                str = ['Specified name '  app.JointAnglesEditField.Value 'was not found in ' dir '!'];
                                uialert(app.HandEyeCalibrationUIFigure, str, 'Loading joint angles failed');
                            end
                    end
                    enableButton(app, app.RemovePoseButton);
                end
            end
        end

        % Button pushed function: OpenDocsButton
        function OpenDocsButtonPushed(app, event)
            winopen('doc.pdf');
        end

        % Button pushed function: OpenHelpButton
        function OpenHelpButtonPushed(app, event)
            winopen('help.pdf');
        end

        % Callback function
        function PosesUITableCellEdit(app, event)
            
        end

        % Cell selection callback: PosesUITable
        function PosesUITableCellSelection(app, event)
            index = event.Indices(1);
            app.selectedPosesRow = index;
            app.RowLabel.Text = num2str(index);
        end

        % Button pushed function: RemoveAllImages
        function RemoveAllImagesButtonPushed(app, event)
            app.ImagesListBox.Items = {};
            app.ImagesListBox.ItemsData = [];
        end

        % Button pushed function: RemoveAllPoses
        function RemoveAllPosesButtonPushed(app, event)
            app.PosesUITable.Data = [];
            app.JointsUITable.Data = [];
        end

        % Button pushed function: RemoveImage
        function RemoveImageButtonPushed(app, event)
            value = app.ImagesListBox.Value;
            items = app.ImagesListBox.Items;
            data = app.ImagesListBox.ItemsData;
            
            if ~isempty(value)
                [res, index] = ismember(cellstr(value), cellstr(app.ImagesListBox.ItemsData));
                
                if res
                    items(index) = [];
                    data(index) = [];
                    
                    app.ImagesListBox.Items = items;
                    app.ImagesListBox.ItemsData = data;
                    
                    dataLen = size(app.ImagesListBox.ItemsData, 2);
                    
                    if index <= dataLen
                        changeEvent.Value = app.ImagesListBox.ItemsData(index);
                    elseif dataLen > 0
                        changeEvent.Value = app.ImagesListBox.ItemsData(dataLen);
                    else
                        changeEvent.Value = {};
                    end
                    
                    changeEvent.PreviousValue = value;
                    changeEvent.Source = app.ImagesListBox;
                    changeEvent.EventName = 'ValueChanged';
                    ImagesListBoxValueChanged(app, changeEvent);
                    
                    % Check if delete poses also
                    if app.RemovePoseCheckBox.Value
                        deletePosesRow(app, index);
                        checkPosesTablesEmpty(app);
                    end
                end
                
                if isempty(app.ImagesListBox.Items)
                    app.ImageView.Title.String = 'Image not selected';
                    cla(app.ImageView);
                    
                    disableButton(app, app.RemoveImage);
                end
            end
            
        end

        % Button pushed function: RemovePoseButton
        function RemovePoseButtonPushed(app, event)
            if app.selectedPosesRow > 0
                prevLenPose = size(app.PosesUITable.Data, 1);
                prevLenJoint = size(app.JointsUITable.Data, 1);
                deletePosesRow(app, app.selectedPosesRow);
                
                if app.selectedPosesRow == prevLenPose
                    app.selectedPosesRow = 0;
                    app.RowLabel.Text = num2str(0);
                end
                
                if app.selectedPosesRow == prevLenJoint
                    app.selectedPosesRow = 0;
                    app.RowLabel.Text = num2str(0);
                end
            end
            
            if isempty(app.PosesUITable.Data) && isempty(app.JointsUITable.Data)
                app.selectedPosesRow = 0;
                app.RowLabel.Text = num2str(0);
                disableButton(app, app.RemovePoseButton);
            end
        end

        % Button pushed function: SaveCalibButton
        function SaveCalibButtonPushed(app, event)
            infoMsg(app, 'Save Hand Eye Calibration', ['Svaed hand eye calibration to ' app.HandEyeCalibrationEditField.Value]);
            
            handEyeCorW = app.handEyeCorrectedWengert;
            handEyeW = app.handEyeWengert;
            handEyeCorL = app.handEyeCorrectedLazax;
            handEyeL = app.handEyeLazax;
            
            save(app.HandEyeCalibrationEditField.Value, 'handEyeCorW', 'handEyeW', 'handEyeCorL', 'handEyeL');
        end

        % Button pushed function: SaveConfigButton
        function SaveConfigButtonPushed(app, event)
            % Create structs
            calib.varDir = app.VariablesEditField.Value;
            calib.imgDir = app.CalibrationImagesEditField.Value;
            calib.jointAngles = app.JointAnglesEditField.Value;
            calib.cartPoses = app.CartesianPosesEditField.Value;
            calib.units = app.UnitsDropDown.Value;
            calib.tileSize = app.TileSizeEditField.Value;
            
            handEyeC.dir = app.HandEyeCalibrationEditField.Value;
            
            sim.dir = app.VrepEditField.Value;
            sim.scene = app.SceneEditField.Value;
            sim.args = app.VrepArgsEditField.Value;
            sim.autoStart = double(app.VrepAutoStartCheckBox.Value);
            sim.reconTimes = double(app.ReconTimesSpinner.Value);
            sim.reconTime = double(app.ReconTimeEditField.Value);
            
            captureSrv.dir = app.CaptureServerEditField.Value;
            captureSrv.args = app.ArgumentsEditField.Value;
            
            ros.masterIP = app.MasterIPEditField.Value;
            ros.localIP = app.LocalIPEditField.Value;
            ros.nodeName = app.NodeNameEditField.Value;
            ros.robotVelocity = app.RobotVelocityEditField.Value;
            
            config.dir = app.ConfigurationEditField.Value;
            
            % Combine all structs to one data
            data = {calib; handEyeC; sim; captureSrv; ros; config};
            
            writeConfig(app.ConfigurationEditField.Value, app.configSections, data);
            str = ['Saved configuration to ' app.ConfigurationEditField.Value];
            logger(app, str);
            infoMsg(app, 'Configuration Saved', str);
        end

        % Button pushed function: SceneBrowseButton
        function SceneBrowseButtonPushed(app, event)
            [path, ~, ~] = fileparts(app.SceneEditField.Value);
            [filename, path] = uigetfile({'*.ttt'},'VREP Scene', path);
            
            if length(filename) > 1
                path = [path filename];
                app.SceneEditField.Value = path;
            end
        end

        % Value changed function: ShowInteraractiveIntrinsicsCheckBox
        function ShowInteraractiveIntrinsicsCheckBoxValueChanged(app, event)
            value = app.ShowInteraractiveIntrinsicsCheckBox.Value;
            if value
                app.extrinsicsFig.Visible = 'on';
                logger(app, 'Enabling interactive extrinsics');
            else
                app.extrinsicsFig.Visible = 'off';
                logger(app, 'Disabling interactive extrinsics');
            end
        end

        % Value changed function: SourceDropDown
        function SourceDropDownValueChanged(app, event)
            value = app.SourceDropDown.Value;
            
            % Disbale tabs depending on source
            if value == app.realRobotName
                
                app.jointTab.Parent = app.PosesTabGroup;
                app.PosesTabGroup.Children = [app.PosesTabGroup.Children(1); app.PosesTabGroup.Children(2)];
                
            elseif value == app.simName
                
                app.jointTab = app.PosesTabGroup.Children(2);
                app.PosesTabGroup.Children(2).Parent = [];
            end
            
        end

        % Button pushed function: StartButton
        function StartButtonPushed(app, event)
            source = app.SourceDropDown.Value;
            res = 1;
            autoSimStart = 0;
            app.simData = 0;
            
            % Adjust labels, lamps buttons
            app.DataProgressLamp.Color = 'yellow';
            app.ProgressLabel.Text = 'Starting';
            disableButton(app, app.StartButton);
            
            % Change app state
            app.runsDataAcquisition = 1;
            
            
            switch source
                % Source = Simulation
                case app.simName
                    if isempty(app.PosesUITable.Data)
                        res = 0;
                        errorMsg(app, 'Cartesian Poses Empty', 'Cartesian poses are empty! Please add or load poses!');
                    else
                        if app.VrepAutoStartCheckBox.Value
                            % Try to connect
                            [app.simCon] = connectToSimulation();
                            
                            % if client closed, start a new one
                            if app.simCon.clientID == -1
                                res = startSimExecutable(app);
                            end
                            autoSimStart = 1;
                        else
                            infoMsg(app, 'Start VREP Manually', 'Please start VREP and load the proper scene manually and procced!');
                        end
                        
                        if res
                            % Connect to client
                            simRes = simConnection(app, autoSimStart);
                            
                            if simRes
                                % Get all handles
                                [handleRes, app.simCon] = initialiseSimHandles(app.simCon);
                                if handleRes
                                    % Start simulation
                                    startSimulation(app.simCon);
                                else
                                    errorMsg(app, 'VREP Connection Error', 'Could not initialize VREP handles!');
                                end
                                
                                res = simRes && handleRes;
                            else
                                errorMsg(app, 'VREP Connection Error', 'Could not connect to VREP! Pleae check log?');
                                res = 0;
                            end            
                        end               
                    end
                    % Source = Real Robot
                case app.realRobotName
                    
                    if isempty(app.JointsUITable.Data)
                        res = 0;
                        errorMsg(app, 'Joint Angles Empty', 'Joint angles are empty! Please add or load joint angles!');
                    else
                        % Start camera capture server
                        srvRes = startCaptureServer(app);
                        
                        if srvRes
                            % Connect to ros
                            rosRes = startROS(app);
                            
                            if ~rosRes
                                errorMsg(app, 'ROS Connection Error', 'Could not start ROS!');
                            end
                            res = srvRes && rosRes;
                        else
                            errorMsg(app, 'Capture Server Connection Error', 'Could not connect to Capture Server! Pleae check log?');
                            res = 0;
                        end
                        
                        
                    end               
            end
            
            if exist(app.CalibrationImagesEditField.Value, 'dir')
                if res
                    app.ProgressLabel.Text = 'Running';
                    
                    % Execute depending on source
                    res = executeSource(app, source);
                    
                    switch source
                        % Source = Simulation
                        case app.simName
                            stopSimulation(app.simCon);
                            app.simData = 1;
                            % Source = Real Robot
                        case app.realRobotName
                            app.simData = 0;
                    end
                    
                    app.DataProgressLamp.Color = 'green';
                    app.ProgressLabel.Text = 'Ready';
                else
                    app.DataProgressLamp.Color = 'red';
                    app.ProgressLabel.Text = 'Error!';
                end
            else
                errorMsg(app, 'Images folder error',...
                    ['Folder ' char(app.CalibrationImagesEditField.Value) ' to store images does not exist! Please check settings!']);
                app.DataProgressLamp.Color = 'red';
                app.ProgressLabel.Text = 'Error!';
            end
            
            app.runsDataAcquisition = 0;
            enableButton(app, app.StartButton);
        end

        % Selection change function: TabGroup
        function TabGroupSelectionChanged(app, event)
            % Switch back to old tab while calibrating or getting data
            if app.runsCalibration || app.runsDataAcquisition
                app.TabGroup.SelectedTab = event.OldValue;
            end            
        end

        % Value changed function: UnitsDropDown
        function UnitsDropDownValueChanged(app, event)
            % Change labels depending on units
            value = app.UnitsDropDown.Value;
            app.UnitsLabel.Text = value;
            changeCartPoseColumnNames(app);
        end

        % Button pushed function: VariablesButton
        function VariablesButtonPushed(app, event)
            path = uigetdir(app.homePath,'Select Variables Directory');
            
            if length(path) > 1
                app.VariablesEditField.Value = path;
            end
        end

        % Button pushed function: VrepBrowseButton
        function VrepBrowseButtonPushed(app, event)
            [path, ~, ~] = fileparts(app.VrepEditField.Value);
            [filename, path] = uigetfile({'*.exe'},'VREP Executable', path);
            
            if length(filename) > 1
                path = [path filename];
                app.VrepEditField.Value = path;
            end
        end

        % Callback function
        function VrepEditFieldValueChanged(app, event)
            value = app.VrepEditField.Value;
            
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.HandEyeCalibrationUIFigure = uifigure;
            app.HandEyeCalibrationUIFigure.Name = 'Hand-Eye Calibration';
            app.HandEyeCalibrationUIFigure.CloseRequestFcn = createCallbackFcn(app, @HandEyeCalibrationUIFigureCloseRequest, true);
            app.TabGroup = uitabgroup(app.HandEyeCalibrationUIFigure);
            app.TabGroup.SelectionChangedFcn = createCallbackFcn(app, @TabGroupSelectionChanged, true);
            app.DataTab = uitab(app.TabGroup);
            app.DataTab.Title = 'Data';
            app.ImagesPanel = uipanel(app.DataTab);
            app.ImagesPanel.Title = 'Images';
            app.ImagesListBox = uilistbox(app.ImagesPanel);
            app.ImagesListBox.Items = {};
            app.ImagesListBox.ValueChangedFcn = createCallbackFcn(app, @ImagesListBoxValueChanged, true);
            app.ImagesListBox.Value = {};
            app.ImageView = uiaxes(app.ImagesPanel);
            title(app.ImageView, 'Image not selected')
            app.PosesPanel = uipanel(app.DataTab);
            app.PosesPanel.Title = 'Poses';
            app.PosesTabGroup = uitabgroup(app.PosesPanel);
            app.JointAnglesTab = uitab(app.PosesTabGroup);
            app.JointAnglesTab.Title = 'Joint Angles';
            app.JointsUITable = uitable(app.JointAnglesTab);
            app.JointsUITable.ColumnName = {'J1 (rad)'; 'J2 (rad)'; 'J3 (rad)'; 'J4 (rad)'; 'J5 (rad)'; 'J6 (rad)'; 'J7 (rad)'};
            app.JointsUITable.RowName = {};
            app.JointsUITable.CellSelectionCallback = createCallbackFcn(app, @JointsUITableCellSelection, true);
            app.CartesianCoordinatesTab = uitab(app.PosesTabGroup);
            app.CartesianCoordinatesTab.Title = 'Cartesian Coordinates';
            app.PosesUITable = uitable(app.CartesianCoordinatesTab);
            app.PosesUITable.ColumnName = {'X'; 'Y'; 'Z'; 'Roll (deg)'; 'Pitch (deg)'; 'Yaw (deg)'};
            app.PosesUITable.RowName = {};
            app.PosesUITable.CellSelectionCallback = createCallbackFcn(app, @PosesUITableCellSelection, true);
            app.ImagesButtonPanel = uipanel(app.DataTab);
            app.LoadImageButton = uibutton(app.ImagesButtonPanel, 'push');
            app.LoadImageButton.ButtonPushedFcn = createCallbackFcn(app, @LoadImageButtonPushed, true);
            app.LoadImageButton.Text = 'Load';
            app.RemoveImage = uibutton(app.ImagesButtonPanel, 'push');
            app.RemoveImage.ButtonPushedFcn = createCallbackFcn(app, @RemoveImageButtonPushed, true);
            app.RemoveImage.Text = 'Remove';
            app.RemovePoseCheckBox = uicheckbox(app.ImagesButtonPanel);
            app.RemovePoseCheckBox.Text = 'Remove Pose';
            app.RemovePoseCheckBox.Value = true;
            app.ImagesLabel = uilabel(app.ImagesButtonPanel);
            app.ImagesLabel.Text = 'Images';
            app.RemoveAllImages = uibutton(app.ImagesButtonPanel, 'push');
            app.RemoveAllImages.ButtonPushedFcn = createCallbackFcn(app, @RemoveAllImagesButtonPushed, true);
            app.RemoveAllImages.Text = 'Remove All';
            app.PosesButtonPanel = uipanel(app.DataTab);
            app.AddPoseButton = uibutton(app.PosesButtonPanel, 'push');
            app.AddPoseButton.ButtonPushedFcn = createCallbackFcn(app, @AddPoseButtonPushed, true);
            app.AddPoseButton.Text = 'Add';
            app.RemovePoseButton = uibutton(app.PosesButtonPanel, 'push');
            app.RemovePoseButton.ButtonPushedFcn = createCallbackFcn(app, @RemovePoseButtonPushed, true);
            app.RemovePoseButton.Text = 'Remove';
            app.LoadPoseButton = uibutton(app.PosesButtonPanel, 'push');
            app.LoadPoseButton.ButtonPushedFcn = createCallbackFcn(app, @LoadPoseButtonPushed, true);
            app.LoadPoseButton.Text = 'Load';
            app.PosesLabel = uilabel(app.PosesButtonPanel);
            app.PosesLabel.Text = 'Poses';
            app.SelectedRowLabel = uilabel(app.PosesButtonPanel);
            app.SelectedRowLabel.Text = 'Selected Row:';
            app.RowLabel = uilabel(app.PosesButtonPanel);
            app.RowLabel.Text = '0';
            app.RemoveAllPoses = uibutton(app.PosesButtonPanel, 'push');
            app.RemoveAllPoses.ButtonPushedFcn = createCallbackFcn(app, @RemoveAllPosesButtonPushed, true);
            app.RemoveAllPoses.Text = 'Remove All';
            app.StartButton = uibutton(app.DataTab, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.StartButton.Text = 'Start';
            app.SourceDropDownLabel = uilabel(app.DataTab);
            app.SourceDropDownLabel.Text = 'Source';
            app.SourceDropDown = uidropdown(app.DataTab);
            app.SourceDropDown.Items = {'Real Robot', 'Simulation'};
            app.SourceDropDown.ValueChangedFcn = createCallbackFcn(app, @SourceDropDownValueChanged, true);
            app.SourceDropDown.Value = 'Real Robot';
            app.DataProgressLamp = uilamp(app.DataTab);
            app.RealTimeExecutionLabel = uilabel(app.DataTab);
            app.RealTimeExecutionLabel.Text = 'Real Time Execution';
            app.RealDataLabel = uilabel(app.DataTab);
            app.RealDataLabel.Text = 'Real Data:';
            app.ProgressLabel = uilabel(app.DataTab);
            app.ProgressLabel.Text = 'N/A';
            app.CalibrationTab = uitab(app.TabGroup);
            app.CalibrationTab.Title = 'Calibration';
            app.CalibrationButtonPanel = uipanel(app.CalibrationTab);
            app.CalibrateButton = uibutton(app.CalibrationButtonPanel, 'push');
            app.CalibrateButton.ButtonPushedFcn = createCallbackFcn(app, @CalibrateButtonPushed, true);
            app.CalibrateButton.Text = 'Calibrate';
            app.SaveCalibButton = uibutton(app.CalibrationButtonPanel, 'push');
            app.SaveCalibButton.ButtonPushedFcn = createCallbackFcn(app, @SaveCalibButtonPushed, true);
            app.SaveCalibButton.Enable = 'off';
            app.SaveCalibButton.Text = 'Save';
            app.TileSizeEditFieldLabel = uilabel(app.CalibrationButtonPanel);
            app.TileSizeEditFieldLabel.Text = 'Tile Size';
            app.TileSizeEditField = uieditfield(app.CalibrationButtonPanel, 'numeric');
            app.TileSizeEditField.Limits = [1e-06 Inf];
            app.TileSizeEditField.Value = 0.0215;
            app.CalibProgress = uilamp(app.CalibrationButtonPanel);
            app.CalibrationLabel = uilabel(app.CalibrationButtonPanel);
            app.CalibrationLabel.Text = 'Calibration:';
            app.CalibProgressLabel = uilabel(app.CalibrationButtonPanel);
            app.CalibProgressLabel.Text = 'N/A';
            app.UnitsLabel = uilabel(app.CalibrationButtonPanel);
            app.UnitsLabel.Text = 'mm';
            app.ShowInteraractiveIntrinsicsCheckBox = uicheckbox(app.CalibrationButtonPanel);
            app.ShowInteraractiveIntrinsicsCheckBox.ValueChangedFcn = createCallbackFcn(app, @ShowInteraractiveIntrinsicsCheckBoxValueChanged, true);
            app.ShowInteraractiveIntrinsicsCheckBox.Text = 'Show Interaractive Intrinsics';
            app.ExtrinsicsPanel = uipanel(app.CalibrationTab);
            app.Extrinsics = uiaxes(app.ExtrinsicsPanel);
            title(app.Extrinsics, 'Extrinsics')
            app.TabGroup2 = uitabgroup(app.ExtrinsicsPanel);
            app.HandEyeCorrectedWTab = uitab(app.TabGroup2);
            app.HandEyeCorrectedWTab.Title = 'Hand Eye Corrected (W)';
            app.HEWCorCalibUITable = uitable(app.HandEyeCorrectedWTab);
            app.HEWCorCalibUITable.ColumnName = {'1'; '2'; '3'; '4'};
            app.HEWCorCalibUITable.RowName = {};
            app.HEWRCorPYUITable = uitable(app.HandEyeCorrectedWTab);
            app.HEWRCorPYUITable.ColumnName = {'X'; 'Y'; 'Z'; 'Roll (deg)'; 'Pitch (deg)'; 'Yaw (deg)'};
            app.HEWRCorPYUITable.RowName = {};
            app.HandEyeWTab = uitab(app.TabGroup2);
            app.HandEyeWTab.Title = 'Hand Eye (W)';
            app.HEWCalibUITable = uitable(app.HandEyeWTab);
            app.HEWCalibUITable.ColumnName = {'1'; '2'; '3'; '4'};
            app.HEWCalibUITable.RowName = {};
            app.HEWRPYUITable = uitable(app.HandEyeWTab);
            app.HEWRPYUITable.ColumnName = {'X'; 'Y'; 'Z'; 'Roll (deg)'; 'Pitch (deg)'; 'Yaw (deg)'};
            app.HEWRPYUITable.RowName = {};
            app.HandEyeCorrectedLTab = uitab(app.TabGroup2);
            app.HandEyeCorrectedLTab.Title = 'Hand Eye Corrected (L)';
            app.HELCorCalibUITable = uitable(app.HandEyeCorrectedLTab);
            app.HELCorCalibUITable.ColumnName = {'1'; '2'; '3'; '4'};
            app.HELCorCalibUITable.RowName = {};
            app.HELRCorPYUITable = uitable(app.HandEyeCorrectedLTab);
            app.HELRCorPYUITable.ColumnName = {'X'; 'Y'; 'Z'; 'Roll (deg)'; 'Pitch (deg)'; 'Yaw (deg)'};
            app.HELRCorPYUITable.RowName = {};
            app.HandEyeLTab = uitab(app.TabGroup2);
            app.HandEyeLTab.Title = 'Hand Eye (L)';
            app.HELCalibUITable = uitable(app.HandEyeLTab);
            app.HELCalibUITable.ColumnName = {'1'; '2'; '3'; '4'};
            app.HELCalibUITable.RowName = {};
            app.HELRPYUITable = uitable(app.HandEyeLTab);
            app.HELRPYUITable.ColumnName = {'X'; 'Y'; 'Z'; 'Roll (deg)'; 'Pitch (deg)'; 'Yaw (deg)'};
            app.HELRPYUITable.RowName = {};
            app.IntrinsicsPanel = uipanel(app.CalibrationTab);
            app.PixelError = uiaxes(app.IntrinsicsPanel);
            title(app.PixelError, 'Reprojection Error')
            app.InstrinsicCalibrationpixelPanel = uipanel(app.IntrinsicsPanel);
            app.InstrinsicCalibrationpixelPanel.Title = 'Instrinsic Calibration (pixel)';
            app.InstrinsiCalibUITable = uitable(app.InstrinsicCalibrationpixelPanel);
            app.InstrinsiCalibUITable.ColumnName = {'1'; '2'; '3'};
            app.InstrinsiCalibUITable.RowName = {};
            app.SettingsTab = uitab(app.TabGroup);
            app.SettingsTab.Title = 'Settings';
            app.ImageCaptureServerPanel = uipanel(app.SettingsTab);
            app.ImageCaptureServerPanel.Title = 'Image Capture Server';
            app.CaptureServerEditFieldLabel = uilabel(app.ImageCaptureServerPanel);
            app.CaptureServerEditFieldLabel.Text = 'Capture Server';
            app.CaptureServerEditField = uieditfield(app.ImageCaptureServerPanel, 'text');
            app.CaptureServerEditField.Editable = 'off';
            app.CaptureServerEditField.Value = '\pgcapture\pgcapture.exe';
            app.ArgumentsEditFieldLabel = uilabel(app.ImageCaptureServerPanel);
            app.ArgumentsEditFieldLabel.Text = 'Arguments';
            app.ArgumentsEditField = uieditfield(app.ImageCaptureServerPanel, 'text');
            app.ArgumentsEditField.Value = '-s -o ';
            app.BrowseCaptureServerButton = uibutton(app.ImageCaptureServerPanel, 'push');
            app.BrowseCaptureServerButton.ButtonPushedFcn = createCallbackFcn(app, @BrowseCaptureServerButtonPushed, true);
            app.BrowseCaptureServerButton.Text = 'Browse';
            app.CalibrationPanel = uipanel(app.SettingsTab);
            app.CalibrationPanel.Title = 'Calibration';
            app.BrowseCalibImgButton = uibutton(app.CalibrationPanel, 'push');
            app.BrowseCalibImgButton.ButtonPushedFcn = createCallbackFcn(app, @BrowseCalibImgButtonPushed, true);
            app.BrowseCalibImgButton.Text = 'Browse';
            app.CalibrationImagesEditFieldLabel = uilabel(app.CalibrationPanel);
            app.CalibrationImagesEditFieldLabel.Text = 'Calibration Images';
            app.CalibrationImagesEditField = uieditfield(app.CalibrationPanel, 'text');
            app.CalibrationImagesEditField.Editable = 'off';
            app.CalibrationImagesEditField.Value = '\images';
            app.VariablesButton = uibutton(app.CalibrationPanel, 'push');
            app.VariablesButton.ButtonPushedFcn = createCallbackFcn(app, @VariablesButtonPushed, true);
            app.VariablesButton.Text = 'Browse';
            app.VariablesEditFieldLabel = uilabel(app.CalibrationPanel);
            app.VariablesEditFieldLabel.Text = 'Variables';
            app.VariablesEditField = uieditfield(app.CalibrationPanel, 'text');
            app.VariablesEditField.Editable = 'off';
            app.VariablesEditField.Value = '\variables';
            app.JointAnglesEditFieldLabel = uilabel(app.CalibrationPanel);
            app.JointAnglesEditFieldLabel.Text = 'Joint Angles';
            app.JointAnglesEditField = uieditfield(app.CalibrationPanel, 'text');
            app.JointAnglesEditField.Value = 'intrinsicCalibrationAnglePositions';
            app.CartesianPosesEditFieldLabel = uilabel(app.CalibrationPanel);
            app.CartesianPosesEditFieldLabel.Text = 'Cartesian Poses';
            app.CartesianPosesEditField = uieditfield(app.CalibrationPanel, 'text');
            app.CartesianPosesEditField.Value = 'intrinsicCalibrationPositions';
            app.CameraParametersEditFieldLabel = uilabel(app.CalibrationPanel);
            app.CameraParametersEditFieldLabel.Text = 'Camera Parameters';
            app.CameraParametersEditField = uieditfield(app.CalibrationPanel, 'text');
            app.CameraParametersEditField.Value = 'cameraParams';
            app.HandEyeCalibrationEditField_2Label_2 = uilabel(app.CalibrationPanel);
            app.HandEyeCalibrationEditField_2Label_2.Text = 'Hand Eye Calibration';
            app.HandEyeCalibrationNameEditField = uieditfield(app.CalibrationPanel, 'text');
            app.HandEyeCalibrationNameEditField.Value = 'handEyeCalibartion';
            app.UnitsDropDownLabel = uilabel(app.CalibrationPanel);
            app.UnitsDropDownLabel.Text = 'Units';
            app.UnitsDropDown = uidropdown(app.CalibrationPanel);
            app.UnitsDropDown.Items = {'Meters', 'Millimeters'};
            app.UnitsDropDown.ItemsData = {'m', 'mm'};
            app.UnitsDropDown.ValueChangedFcn = createCallbackFcn(app, @UnitsDropDownValueChanged, true);
            app.UnitsDropDown.Value = 'm';
            app.LoadConfigButton = uibutton(app.SettingsTab, 'push');
            app.LoadConfigButton.ButtonPushedFcn = createCallbackFcn(app, @LoadConfigButtonPushed, true);
            app.LoadConfigButton.Text = 'Load';
            app.SaveConfigButton = uibutton(app.SettingsTab, 'push');
            app.SaveConfigButton.ButtonPushedFcn = createCallbackFcn(app, @SaveConfigButtonPushed, true);
            app.SaveConfigButton.Text = 'Save';
            app.ConfigurationPanel = uipanel(app.SettingsTab);
            app.ConfigurationPanel.Title = 'Configuration';
            app.BrowseConfig = uibutton(app.ConfigurationPanel, 'push');
            app.BrowseConfig.ButtonPushedFcn = createCallbackFcn(app, @BrowseConfigButtonPushed, true);
            app.BrowseConfig.Text = 'Browse';
            app.ConfigurationEditFieldLabel = uilabel(app.ConfigurationPanel);
            app.ConfigurationEditFieldLabel.Text = 'Configuration';
            app.ConfigurationEditField = uieditfield(app.ConfigurationPanel, 'text');
            app.ConfigurationEditField.Value = '\handeye_app.ini';
            app.HandEyeCalibrationSettingsPanel = uipanel(app.SettingsTab);
            app.HandEyeCalibrationSettingsPanel.Title = 'Hand Eye Calibration';
            app.BrowseHandEye = uibutton(app.HandEyeCalibrationSettingsPanel, 'push');
            app.BrowseHandEye.ButtonPushedFcn = createCallbackFcn(app, @BrowseHandEyeButtonPushed, true);
            app.BrowseHandEye.Text = 'Browse';
            app.HandEyeCalibrationEditField_2Label = uilabel(app.HandEyeCalibrationSettingsPanel);
            app.HandEyeCalibrationEditField_2Label.Text = 'Hand Eye Calibration';
            app.HandEyeCalibrationEditField = uieditfield(app.HandEyeCalibrationSettingsPanel, 'text');
            app.HandEyeCalibrationEditField.Value = '\handEye.mat';
            app.VrepSimulationPanel = uipanel(app.SettingsTab);
            app.VrepSimulationPanel.Title = 'VREP Simualtion';
            app.SimulationLabel = uilabel(app.VrepSimulationPanel);
            app.SimulationLabel.Text = 'Simulation';
            app.VrepEditField = uieditfield(app.VrepSimulationPanel, 'text');
            app.VrepEditField.Editable = 'off';
            app.VrepEditField.Value = 'C:\Program Files\V-REP3\V-REP_PRO_EDU\vrep.exe';
            app.VrepBrowseButton = uibutton(app.VrepSimulationPanel, 'push');
            app.VrepBrowseButton.ButtonPushedFcn = createCallbackFcn(app, @VrepBrowseButtonPushed, true);
            app.VrepBrowseButton.Text = 'Browse';
            app.SceneEditFieldLabel = uilabel(app.VrepSimulationPanel);
            app.SceneEditFieldLabel.Text = 'Scene';
            app.SceneEditField = uieditfield(app.VrepSimulationPanel, 'text');
            app.SceneEditField.Value = '\app\scenes\simulation_setting_aligned_norobot.ttt';
            app.SceneBrowseButton = uibutton(app.VrepSimulationPanel, 'push');
            app.SceneBrowseButton.ButtonPushedFcn = createCallbackFcn(app, @SceneBrowseButtonPushed, true);
            app.SceneBrowseButton.Text = 'Browse';
            app.ArgumentsEditField_2Label = uilabel(app.VrepSimulationPanel);
            app.ArgumentsEditField_2Label.Text = 'Arguments';
            app.VrepArgsEditField = uieditfield(app.VrepSimulationPanel, 'text');
            app.VrepArgsEditField.Value = '-h';
            app.VrepAutoStartCheckBox = uicheckbox(app.VrepSimulationPanel);
            app.VrepAutoStartCheckBox.Text = 'Start VREP automatically';
            app.VrepAutoStartCheckBox.Value = true;
            app.ReconnecttimesSpinnerLabel = uilabel(app.VrepSimulationPanel);
            app.ReconnecttimesSpinnerLabel.Text = 'Reconnect times';
            app.ReconTimesSpinner = uispinner(app.VrepSimulationPanel);
            app.ReconTimesSpinner.Limits = [1 100];
            app.ReconTimesSpinner.Value = 10;
            app.ReconnecttimesEditFieldLabel = uilabel(app.VrepSimulationPanel);
            app.ReconnecttimesEditFieldLabel.Text = 'Reconnect time (s)';
            app.ReconTimeEditField = uieditfield(app.VrepSimulationPanel, 'numeric');
            app.ReconTimeEditField.Limits = [1 Inf];
            app.ReconTimeEditField.Value = 1;
            app.ROSPanel = uipanel(app.SettingsTab);
            app.ROSPanel.Title = 'ROS';
            app.NodeNameEditFieldLabel = uilabel(app.ROSPanel);
            app.NodeNameEditFieldLabel.Text = 'Node Name';
            app.NodeNameEditField = uieditfield(app.ROSPanel, 'text');
            app.NodeNameEditField.Value = '/node_matlab4ros';
            app.MasterIPEditFieldLabel = uilabel(app.ROSPanel);
            app.MasterIPEditFieldLabel.Text = 'Master IP';
            app.MasterIPEditField = uieditfield(app.ROSPanel, 'text');
            app.MasterIPEditField.Value = '10.0.0.1';
            app.LocalIPEditFieldLabel = uilabel(app.ROSPanel);
            app.LocalIPEditFieldLabel.Text = 'Local IP';
            app.LocalIPEditField = uieditfield(app.ROSPanel, 'text');
            app.LocalIPEditField.Value = '10.0.0.106';
            app.RobotVelocityEditFieldLabel = uilabel(app.ROSPanel);
            app.RobotVelocityEditFieldLabel.Text = 'Robot Velocity';
            app.RobotVelocityEditField = uieditfield(app.ROSPanel, 'numeric');
            app.RobotVelocityEditField.Limits = [0.1 1];
            app.RobotVelocityEditField.Value = 0.1;
            app.LogTab = uitab(app.TabGroup);
            app.LogTab.Title = 'Log';
            app.Log = uitextarea(app.LogTab);
            app.Log.Editable = 'off';
            app.Log.Value = {'Hand Eye Calibration'};
            app.HelpTab = uitab(app.TabGroup);
            app.HelpTab.Title = 'Help';
            app.OpenHelpButton = uibutton(app.HelpTab, 'push');
            app.OpenHelpButton.ButtonPushedFcn = createCallbackFcn(app, @OpenHelpButtonPushed, true);
            app.OpenHelpButton.Text = 'Open Help ';
            app.OpenDocsButton = uibutton(app.HelpTab, 'push');
            app.OpenDocsButton.ButtonPushedFcn = createCallbackFcn(app, @OpenDocsButtonPushed, true);
            app.OpenDocsButton.Text = 'Open Docs';
            app.ScanObjectsTab = uitab(app.TabGroup);
            app.ScanObjectsTab.Title = 'Scan Objects';
        end
    end

    methods (Access = public)

        % Construct app
        function app = handEyeCalibration

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.HandEyeCalibrationUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.HandEyeCalibrationUIFigure)
        end
    end
end